classdef SIR_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        BetaKnob_2Label                matlab.ui.control.Label
        BetaKnob                       matlab.ui.control.Knob
        GammaKnobLabel                 matlab.ui.control.Label
        GammaKnob                      matlab.ui.control.Knob
        StartButton                    matlab.ui.control.StateButton
        TotalPopulationEditFieldLabel  matlab.ui.control.Label
        TotalPopulationEditField       matlab.ui.control.NumericEditField
        RemoviedEditFieldLabel         matlab.ui.control.Label
        RemoviedEditField              matlab.ui.control.NumericEditField
        InfectedEditFieldLabel         matlab.ui.control.Label
        InfectedEditField              matlab.ui.control.NumericEditField
        SusceptibleEditFieldLabel      matlab.ui.control.Label
        SusceptibleEditField           matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        AlphaKnobLabel                 matlab.ui.control.Label
        AlphaKnob                      matlab.ui.control.Knob
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        TimeKnobLabel                  matlab.ui.control.Label
        TimeKnob                       matlab.ui.control.Knob
        HoldSwitchLabel                matlab.ui.control.Label
        HoldSwitch                     matlab.ui.control.Switch
        LampLabel                      matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        StCheckBox                     matlab.ui.control.CheckBox
        ItCheckBox                     matlab.ui.control.CheckBox
        RtCheckBox                     matlab.ui.control.CheckBox
    end

    methods (Access = private)

        % Value changed function: AlphaKnob, BetaKnob, GammaKnob, 
        % HoldSwitch, InfectedEditField, ItCheckBox, RemoviedEditField, 
        % RtCheckBox, StCheckBox, StartButton, SusceptibleEditField, 
        % TimeKnob, TotalPopulationEditField
        function StartButtonValueChanged(app, event)
            
           
            N = app.TotalPopulationEditField.Value;
            S = app.SusceptibleEditField.Value;
            I = app.InfectedEditField.Value;
            R = app.RemoviedEditField.Value;
            T = app.TimeKnob.Value;
            
            if strcmp(app.HoldSwitch.Value,'Off')
                app.UIAxes.cla;
                app.Lamp.Color = 'r';
            else
                app.Lamp.Color = 'g';
            end
            
            value = app.StartButton.Value;
            alpha = app.AlphaKnob.Value;
            beta = app.BetaKnob.Value;
            gamma = app.GammaKnob.Value;
            RealAlpha = alpha/100;
            If=RealAlpha*beta/N; %stopa infekcije
            Rr=1/gamma; %stopa oporavka
            
             f = @(t,x) [-If*x(1)*x(2);If*x(1)*x(2)-Rr*x(2);Rr*x(2)] %Model SIR
             [t,xa]=ode45(f,[0 T], [S I R]); %Diferencial equation solution
              
             hold(app.UIAxes,'on')
             
             if app.StCheckBox.Value == 1
              plot(app.UIAxes,t,xa(:,1),'b') 
             end
             if app.ItCheckBox.Value == 1
              plot(app.UIAxes,t,xa(:,2),'r')
             end
             if app.RtCheckBox.Value == 1
              plot(app.UIAxes,t,xa(:,3),'g')
             end
             if app.StCheckBox.Value == 1 && app.ItCheckBox.Value == 1 && app.RtCheckBox.Value == 1
              legend(app.UIAxes,{'S(t) = Susceptible','I(t) = Infected','R(t) = Removied'});
             else
                 legend(app.UIAxes,'off')
             end
             
             hold(app.UIAxes,'off')


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'SIR model')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Number of Individuals')
            app.BetaKnob_2Label = uilabel(app.UIFigure);
            app.BetaKnob_2Label.Text = 'Beta';
            app.BetaKnob = uiknob(app.UIFigure, 'continuous');
            app.BetaKnob.MajorTicks = [0 3 6 9 12 15 18 21 24 27 30 33 36 39 42 45 48 51 54 57 60 63 66 69 72 75 78 81 84 87 90 93 96 100];
            app.BetaKnob.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.BetaKnob.Value = 2;
            app.GammaKnobLabel = uilabel(app.UIFigure);
            app.GammaKnobLabel.Text = 'Gamma';
            app.GammaKnob = uiknob(app.UIFigure, 'continuous');
            app.GammaKnob.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.GammaKnob.Value = 20;
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.TotalPopulationEditFieldLabel = uilabel(app.UIFigure);
            app.TotalPopulationEditFieldLabel.Text = 'Total Population';
            app.TotalPopulationEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalPopulationEditField.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.TotalPopulationEditField.Value = 1000;
            app.RemoviedEditFieldLabel = uilabel(app.UIFigure);
            app.RemoviedEditFieldLabel.Text = 'Removied';
            app.RemoviedEditField = uieditfield(app.UIFigure, 'numeric');
            app.RemoviedEditField.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.InfectedEditFieldLabel = uilabel(app.UIFigure);
            app.InfectedEditFieldLabel.Text = 'Infected';
            app.InfectedEditField = uieditfield(app.UIFigure, 'numeric');
            app.InfectedEditField.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.InfectedEditField.Value = 3;
            app.SusceptibleEditFieldLabel = uilabel(app.UIFigure);
            app.SusceptibleEditFieldLabel.Text = 'Susceptible';
            app.SusceptibleEditField = uieditfield(app.UIFigure, 'numeric');
            app.SusceptibleEditField.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.SusceptibleEditField.Value = 997;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {' Alpha - probability of infection if someone is'; ' in direct contact with infected individual,'; ' Expressed in percentage %'};
            app.AlphaKnobLabel = uilabel(app.UIFigure);
            app.AlphaKnobLabel.Text = 'Alpha';
            app.AlphaKnob = uiknob(app.UIFigure, 'continuous');
            app.AlphaKnob.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.AlphaKnob.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.AlphaKnob.Value = 50;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {' Beta - Number of individuals that are in direct'; ' contact with a infected individual per day.'};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {' Gamma - Number of days needed for a'; ' person to recover or die.'};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {' Note:'; ''; ' Sum of Susceptible,Infected and Removied'; ' must be equal to total population.'; '                  N = S + I + R'};
            app.TimeKnobLabel = uilabel(app.UIFigure);
            app.TimeKnobLabel.Text = 'Time';
            app.TimeKnob = uiknob(app.UIFigure, 'continuous');
            app.TimeKnob.Limits = [0 10000];
            app.TimeKnob.MajorTicks = [0 1000 2000 3000 4000 5000 6000 7000 8000 9000 10000];
            app.TimeKnob.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.TimeKnob.Value = 100;
            app.HoldSwitchLabel = uilabel(app.UIFigure);
            app.HoldSwitchLabel.Text = 'Hold';
            app.HoldSwitch = uiswitch(app.UIFigure, 'slider');
            app.HoldSwitch.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.StCheckBox = uicheckbox(app.UIFigure);
            app.StCheckBox.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StCheckBox.Text = 'S(t)';
            app.StCheckBox.Value = true;
            app.ItCheckBox = uicheckbox(app.UIFigure);
            app.ItCheckBox.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.ItCheckBox.Text = 'I(t)';
            app.ItCheckBox.Value = true;
            app.RtCheckBox = uicheckbox(app.UIFigure);
            app.RtCheckBox.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.RtCheckBox.Text = 'R(t)';
            app.RtCheckBox.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = SIR_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end